classdef MatrixCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InputdatafromtxtfileButton  matlab.ui.control.Button
        XTextArea                   matlab.ui.control.TextArea
        XTextAreaLabel              matlab.ui.control.Label
        SolveButton                 matlab.ui.control.Button
        InputyourdataTextArea       matlab.ui.control.TextArea
        InputyourdataTextAreaLabel  matlab.ui.control.Label
        ChoosemethodDropDown        matlab.ui.control.DropDown
        ChoosemethodDropDownLabel   matlab.ui.control.Label
    end


    methods (Access = public)
        
        function methodChoose = mapToEnum(~, value, SolverMethod)
            switch value
                case 'Cramer''s Method'
                    methodChoose = SolverMethod.CramersMethod;
                case 'Gauss Method'
                    methodChoose = SolverMethod.GaussMethod;
                case 'Seidel Method'
                    methodChoose = SolverMethod.SeidelMethod;
                case 'Gauss-Jordan Method'
                    methodChoose = SolverMethod.GaussJordanMethod;
                case 'Jacobi Method'
                    methodChoose = SolverMethod.JacobiMethod;
                otherwise
                    methodChoose = []; % Handle unknown method
            end
        end

        function X = cramerMethod(~, coefficientMatrix, vector)
            % Check if coefficientMatrix is square
            [rows, cols] = size(coefficientMatrix);
            if rows ~= cols
                error('Coefficient matrix must be square for Cramer''s method.');
            end
            
            % Check if coefficientMatrix is non-singular
            if abs(det(coefficientMatrix)) < eps
                error('Coefficient matrix must be non-singular for Cramer''s method.');
            end
        
            % Get the number of equations
            n = rows;
            
            % Initialize solution vector X
            X = zeros(n, 1);
            
            % Calculate determinant of coefficientMatrix
            detA = det(coefficientMatrix);
            
            % Solve for each variable using Cramer's rule
            for i = 1:n
                % Create a copy of coefficientMatrix and replace i-th column with the vector
                tempMatrix = coefficientMatrix;
                tempMatrix(:, i) = vector;
                
                % Calculate determinant of modified matrix
                detAi = det(tempMatrix);
                
                % Calculate the i-th component of the solution vector
                X(i) = detAi / detA;
            end
        end

        function X = seidelMethod(~, coefficientMatrix, vector)
            % Define constants for maximum iterations and tolerance
            MAX_ITERATIONS = 1000;
            TOLERANCE = 1e-6;
        
            % Check if coefficientMatrix is square
            [rows, cols] = size(coefficientMatrix);
            if rows ~= cols
                error('Coefficient matrix must be square for Seidel method.');
            end
        
            % Check if coefficientMatrix is diagonally dominant
            diagonal = abs(diag(coefficientMatrix));
            offDiagonal = sum(abs(coefficientMatrix), 2) - diagonal;
            if any(diagonal <= offDiagonal)
                warning('Coefficient matrix is not diagonally dominant. Convergence may not be guaranteed.');
            end
        
            % Initialize solution vector X
            X = zeros(rows, 1);
        
            % Perform Seidel iteration
            for iter = 1:MAX_ITERATIONS
                % Store the current solution vector
                X_old = X;
        
                % Update each component of X using the latest available values
                for i = 1:rows
                    sigma = coefficientMatrix(i, 1:i-1) * X(1:i-1) + coefficientMatrix(i, i+1:end) * X_old(i+1:end);
                    X(i) = (vector(i) - sigma) / coefficientMatrix(i, i);
                end
        
                % Check for convergence
                if norm(X - X_old, inf) < TOLERANCE
                    fprintf('Converged after %d iterations.\n', iter);
                    break;
                end
            end
        
            % Check for maximum iterations reached
            if iter == MAX_ITERATIONS
                warning('Maximum iterations reached without convergence.');
            end
        end

        function X = gaussMethod(~, coefficientMatrix, vector)
            % Check if coefficientMatrix is square
            [rows, cols] = size(coefficientMatrix);
            if rows ~= cols
                error('Coefficient matrix must be square for Gauss method.');
            end
        
            % Check if coefficientMatrix is non-singular
            if abs(det(coefficientMatrix)) < eps
                error('Coefficient matrix must be non-singular for Gauss method.');
            end
        
            % Get the number of equations
            n = rows;
        
            % Augment the coefficient matrix with the vector
            augmentedMatrix = [coefficientMatrix, vector];
        
            % Perform forward elimination
            for i = 1:n-1
                % Partial pivoting
                [maxVal, maxIndex] = max(abs(augmentedMatrix(i:n,i)));
                maxIndex = maxIndex + i - 1;
                if maxIndex ~= i
                    augmentedMatrix([i, maxIndex], :) = augmentedMatrix([maxIndex, i], :);
                end
        
                % Eliminate coefficients below pivot
                for j = i+1:n
                    factor = augmentedMatrix(j,i) / augmentedMatrix(i,i);
                    augmentedMatrix(j,:) = augmentedMatrix(j,:) - factor * augmentedMatrix(i,:);
                end
            end
        
            % Perform back substitution
            X = zeros(n, 1);
            X(n) = augmentedMatrix(n,n+1) / augmentedMatrix(n,n);
            for i = n-1:-1:1
                X(i) = (augmentedMatrix(i,n+1) - augmentedMatrix(i,i+1:n) * X(i+1:n)) / augmentedMatrix(i,i);
            end
        end

        function X = gaussJordanMethod(~, coefficientMatrix, vector)
            % Check if coefficientMatrix is square
            [rows, cols] = size(coefficientMatrix);
            if rows ~= cols
                error('Coefficient matrix must be square for Gauss-Jordan method.');
            end
        
            % Check if coefficientMatrix is non-singular
            if abs(det(coefficientMatrix)) < eps
                error('Coefficient matrix must be non-singular for Gauss-Jordan method.');
            end
        
            % Get the number of equations
            n = rows;
        
            % Augment the coefficient matrix with the vector
            augmentedMatrix = [coefficientMatrix, vector];
        
            % Perform forward elimination
            for i = 1:n
                % Partial pivoting
                [maxVal, maxIndex] = max(abs(augmentedMatrix(i:n,i)));
                maxIndex = maxIndex + i - 1;
                if maxIndex ~= i
                    augmentedMatrix([i, maxIndex], :) = augmentedMatrix([maxIndex, i], :);
                end
        
                % Eliminate coefficients above and below pivot
                for j = [1:i-1, i+1:n]
                    factor = augmentedMatrix(j,i) / augmentedMatrix(i,i);
                    augmentedMatrix(j,:) = augmentedMatrix(j,:) - factor * augmentedMatrix(i,:);
                end
            end
        
            % Perform back substitution
            for i = 1:n
                augmentedMatrix(i,:) = augmentedMatrix(i,:) / augmentedMatrix(i,i);
                for j = [1:i-1, i+1:n]
                    augmentedMatrix(j,:) = augmentedMatrix(j,:) - augmentedMatrix(j,i) * augmentedMatrix(i,:);
                end
            end
        
            % Extract the solution vector
            X = augmentedMatrix(:, end);
        end

        function X = jacobiMethod(~, coefficientMatrix, vector)
            % Define constants for maximum iterations and tolerance
            MAX_ITERATIONS = 1000;
            TOLERANCE = 1e-6;
            
            % Check if coefficientMatrix is square
            [rows, cols] = size(coefficientMatrix);
            if rows ~= cols
                error('Coefficient matrix must be square for Jacobi method.');
            end
            
            % Check if coefficientMatrix is diagonally dominant
            diagonal = abs(diag(coefficientMatrix));
            offDiagonal = sum(abs(coefficientMatrix), 2) - diagonal;
            if any(diagonal <= offDiagonal)
                warning('Coefficient matrix is not diagonally dominant. Convergence may not be guaranteed.');
            end
            
            % Initialize solution vector X
            X = zeros(rows, 1);
            
            % Perform Jacobi iteration
            for iter = 1:MAX_ITERATIONS
                % Store the current solution vector
                X_old = X;
                
                % Compute the new solution vector
                for i = 1:rows
                    sigma = 0;
                    for j = 1:cols
                        if j ~= i
                            sigma = sigma + coefficientMatrix(i, j) * X_old(j);
                        end
                    end
                    X(i) = (vector(i) - sigma) / coefficientMatrix(i, i);
                end
                
                % Check for convergence
                if norm(X - X_old, inf) < TOLERANCE
                    fprintf('Converged after %d iterations.\n', iter);
                    break;
                end
            end
            
            % Check for maximum iterations reached
            if iter == MAX_ITERATIONS
                warning('Maximum iterations reached without convergence.');
            end
        end

        function [coefficientMatrix, vector, numEquations] = parseTextAreaData(app)
            % Get the content of the TextArea
            inputData = app.InputyourdataTextArea.Value;
            
            % Split the input by newline characters
            lines = split(inputData, '\n');
            
            % Extract the number of equations
            numEquations = str2double(lines{1});
            
            % Initialize variables for the coefficient matrix and vector
            coefficientMatrix = zeros(numEquations, numEquations);
            vector = zeros(numEquations, 1);
            
            % Parse the input data to populate the coefficient matrix and vector
            for i = 2:numEquations+1
                % Split each line by spaces
                elements = str2double(strsplit(lines{i}));
                
                % Check if the number of elements is correct
                if numel(elements) ~= numEquations + 1
                    error('Invalid input format: each line should contain %d coefficients and one vector element.', numEquations);
                end
                
                % Extract the coefficients (excluding the last element which is the vector entry)
                coefficientMatrix(i-1, :) = elements(1:end-1);
                
                % Extract the vector entry
                vector(i-1) = elements(end);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChoosemethodDropDown
        function ChoosemethodDropDownValueChanged(app, event)

        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            [coefficientMatrix, vector, numEquations] = parseTextAreaData(app);
            
            % Check if the input data is valid
            if isnan(numEquations) || numEquations <= 0 || numel(vector) ~= numEquations || any(isnan(coefficientMatrix(:)))
                % Display a warning message if the input data is invalid
                warning('Invalid input data.');
                return;
            end
        
            % Get the selected method from the dropdown menu
            selectedMethod = app.ChoosemethodDropDown.Value;
        
            % Validate the selected method
            supportedMethods = {'Cramer''s Method', 'Gauss Method', 'Seidel Method', 'Gauss-Jordan Method', 'Jacobi Method'};
            if ~ismember(selectedMethod, supportedMethods)
                % Display a warning message if the selected method is not supported
                warning('Selected method is not supported.');
                return; % Exit the function
            end
        
            % Now you have the coefficient matrix, vector, and selected method, you can proceed with solving the equations
            % Call the corresponding solver method based on the selected method
            switch selectedMethod
                case 'Cramer''s Method'
                    % Call the Cramer's method solver function
                    X = cramerMethod(app, coefficientMatrix, vector);
                case 'Gauss Method'
                    % Call the Gauss method solver function
                    X = gaussMethod(app, coefficientMatrix, vector);
                case 'Seidel Method'
                    % Call the Seidel method solver function
                    X = seidelMethod(app, coefficientMatrix, vector);
                case 'Gauss-Jordan Method'
                    % Call the Gauss-Jordan method solver function
                    X = gaussJordanMethod(app, coefficientMatrix, vector);
                case 'Jacobi Method'
                    % Call the Jacobi method solver function
                    X = jacobiMethod(app, coefficientMatrix, vector);
            end
        
            % Format the solution as a string in vertical order
            solutionStr = ''; % Initialize an empty string
            for i = 1:numel(X)
                % Convert the number to a string
                numStr = num2str(X(i));
                
                % Append the number to the solution string
                solutionStr = [solutionStr numStr newline];
            end
            
            % Set the solution string as the value of the XTextArea
            app.XTextArea.Value = solutionStr;
        end

        % Button pushed function: InputdatafromtxtfileButton
        function InputdatafromtxtfileButtonPushed(app, event)
            % Open a file dialog to select a text file
            [filename, path] = uigetfile({'*.txt','Text files (*.txt)'},'Select Text File');
            
            % Check if the user canceled the selection
            if isequal(filename, 0)
                disp('User canceled the operation.');
                return;
            end
            
            % Read the contents of the selected file
            try
                fileContent = fileread(fullfile(path, filename));
            catch
                disp('Error reading file.');
                return;
            end
    
            % Display the file content in the InputyourdataTextArea
            app.InputyourdataTextArea.Value = fileContent;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'cool');
            app.UIFigure.Name = 'MATLAB App';
            app.ChoosemethodDropDownLabel = uilabel(app.UIFigure);
            app.ChoosemethodDropDownLabel.Text = 'Choose method';
            app.ChoosemethodDropDown = uidropdown(app.UIFigure);
            app.ChoosemethodDropDown.Items = {'Cramer''s Method', 'Gauss Method', 'Seidel Method', 'Gauss-Jordan Method', 'Jacobi Method'};
            app.ChoosemethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosemethodDropDownValueChanged, true);
            app.ChoosemethodDropDown.Value = 'Cramer''s Method';
            app.InputyourdataTextAreaLabel = uilabel(app.UIFigure);
            app.InputyourdataTextAreaLabel.Text = 'Input your data';
            app.InputyourdataTextArea = uitextarea(app.UIFigure);
            app.InputyourdataTextArea.Placeholder = 'Put the data as the instruction recomends';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.XTextAreaLabel = uilabel(app.UIFigure);
            app.XTextAreaLabel.Text = 'X';
            app.XTextArea = uitextarea(app.UIFigure);
            app.XTextArea.Editable = 'off';
            app.XTextArea.Placeholder = 'Result';
            app.InputdatafromtxtfileButton = uibutton(app.UIFigure, 'push');
            app.InputdatafromtxtfileButton.ButtonPushedFcn = createCallbackFcn(app, @InputdatafromtxtfileButtonPushed, true);
            app.InputdatafromtxtfileButton.Text = 'Input data from .txt file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatrixCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end