classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        XTextArea                       matlab.ui.control.TextArea
        XTextAreaLabel                  matlab.ui.control.Label
        InputdatafromtxtfileButton      matlab.ui.control.Button
        ChoosemethodDropDown            matlab.ui.control.DropDown
        ChoosemethodDropDownLabel       matlab.ui.control.Label
        InputtheequationEditField       matlab.ui.control.EditField
        InputtheequationEditFieldLabel  matlab.ui.control.Label
        SolveButton                     matlab.ui.control.Button
    end

    methods (Access = public)
        function X = cramer_method(A, B)
        % Check if A is square
        [n, m] = size(A);
        if n ~= m
            error('Coefficient matrix must be square');
        end
        
        % Check if A is singular
        if abs(det(A)) < eps
            error('Coefficient matrix is singular, system may not have a unique solution');
        end
        
        % Check if number of equations matches length of B
        if n ~= length(B)
            error('Number of equations must match the length of the vector of free terms');
        end
        
        % Solve system using Cramer's method
        X = zeros(n, 1);
    
        for i = 1:n
            % Replace the ith column of A with B
            Ai = A;
            Ai(:, i) = B;
            
            % Calculate the determinant of the modified matrix
            det_Ai = det(Ai);
            
            % Calculate Xi using Cramer's rule
            X(i) = det_Ai / det(A);
        end
    end


    % Callback functions for button actions
    methods (Access = private)
        % Callback function for SolveButton
        function solveButtonPushed(app, event)
            % Get the selected method from the dropdown menu
            method = app.ChoosemethodDropDown.Value;
            
            % Get the input equation from the edit field
            equation = app.InputtheequationEditField.Value;
            
            % Convert the equation to matrices A and B
            equationLines = splitlines(equation); % Split the input into lines
            numEquations = str2double(equationLines{1}); % Extract the number of equations
            A = zeros(numEquations, numEquations); % Initialize matrix A
            B = zeros(numEquations, 1); % Initialize vector B
            
            % Parse the coefficient matrix and vector from the input lines
            for i = 2:numEquations+1
                % Split each line into individual elements
                elements = str2double(strsplit(equationLines{i}));
                
                % Extract the coefficients (excluding the last element which is the vector entry)
                A(i-1, :) = elements(1:end-1);
                
                % Extract the vector entry
                B(i-1) = elements(end);
            end
            
            % Call the corresponding method function
            switch method
                case 'Cramer''s Method'
                    X = cramer_method(A, B);
                case 'Gauss Method'
                    X = gauss_method(A, B);
                case 'Seidel Method'
                    % You need to provide tolerance and max_iterations as arguments
                    X = seidel_method(A, B, tolerance, max_iterations);
                case 'Gauss-Jordan Method'
                    X = gauss_jordan_method(A, B);
                case 'Jacobi Method'
                    % You need to provide tolerance and max_iterations as arguments
                    X = jacobi_method(A, B, tolerance, max_iterations);
            end
            
            % Display the result in the XTextArea
            app.XTextArea.Value = num2str(X);
        end

        % Callback function for InputdatafromtxtfileButton
        function inputFromFileButtonPushed(app, event)
            % Open a dialog box to select the text file
            [filename, filepath] = uigetfile('*.txt', 'Select the text file');
    
            % Check if the user cancelled the operation
            if isequal(filename, 0)
                disp('Operation cancelled');
                return;
            end
    
            % Read the content of the text file
            fileContent = fileread(fullfile(filepath, filename));
    
            % Split the file content into lines
            lines = strsplit(fileContent, '\n');
    
            % Extract the number of equations from the first line
            numEquations = str2double(lines{1});
    
            % Extract the coefficient matrix and vector from the remaining lines
            coefficients = [];
            vector = [];
            for i = 2:numEquations+1
                % Split each line into individual elements
                elements = str2double(strsplit(lines{i}));
    
                % Extract the coefficients (excluding the last element which is the vector entry)
                coefficients = [coefficients; elements(1:end-1)];
    
                % Extract the vector entry
                vector = [vector; elements(end)];
            end
    
            % Convert coefficients and vector to string format
            coefficientsStr = mat2str(coefficients);
            vectorStr = mat2str(vector);
    
            % Update the input equation edit field with the data from the file
            app.InputtheequationEditField.Value = [num2str(numEquations) newline coefficientsStr newline vectorStr];
        end
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.Text = 'Solve';
            app.InputtheequationEditFieldLabel = uilabel(app.UIFigure);
            app.InputtheequationEditFieldLabel.Text = 'Input the equation';
            app.InputtheequationEditField = uieditfield(app.UIFigure, 'text');
            app.ChoosemethodDropDownLabel = uilabel(app.UIFigure);
            app.ChoosemethodDropDownLabel.Text = 'Choose method';
            app.ChoosemethodDropDown = uidropdown(app.UIFigure);
            app.ChoosemethodDropDown.Items = {'Cramer''s Method', 'Gauss Method', 'Seidel Method', 'Gauss-Jordan Method', 'Jacobi Method'};
            app.ChoosemethodDropDown.Value = 'Cramer''s Method';
            app.InputdatafromtxtfileButton = uibutton(app.UIFigure, 'push');
            app.InputdatafromtxtfileButton.Text = 'Input data from .txt file';
            app.XTextAreaLabel = uilabel(app.UIFigure);
            app.XTextAreaLabel.Text = 'X:';
            app.XTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end