classdef IntegralsAndDifferentialsCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FirstproblemtableLabel      matlab.ui.control.Label
        Table                       matlab.ui.control.Table
        LowerboundEditField         matlab.ui.control.NumericEditField
        LowerboundEditFieldLabel    matlab.ui.control.Label
        UpperboundEditField         matlab.ui.control.NumericEditField
        UpperboundEditFieldLabel    matlab.ui.control.Label
        ProblemsListBox             matlab.ui.control.ListBox
        ListofproblemsListBoxLabel  matlab.ui.control.Label
        ResultTextArea              matlab.ui.control.TextArea
        OutputTextAreaLabel         matlab.ui.control.Label
        y0EditField                 matlab.ui.control.NumericEditField
        y0startvalueEditFieldLabel  matlab.ui.control.Label
        StepEditField               matlab.ui.control.NumericEditField
        StepnumberEditFieldLabel    matlab.ui.control.Label
        SolveButton                 matlab.ui.control.Button
        ProblemTypeButtonGroup      matlab.ui.container.ButtonGroup
        DifferentialButton          matlab.ui.control.RadioButton
        IntegralButton              matlab.ui.control.RadioButton
        MethodsDropDown             matlab.ui.control.DropDown
        TakeaMethodDropDownLabel    matlab.ui.control.Label
        XYAxes                      matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ProblemTypeButtonGroup
        function ProblemTypeButtonGroupValueChanged(app, event)
            % Get the selected problem type
            problemType = app.ProblemTypeButtonGroup.SelectedObject.Text;
        
            % Show/hide numeric fields based on problem type
            if strcmp(problemType, 'Integral')
                app.y0EditField.Visible = 'off'; % Hide y0 start value field
                app.ProblemsListBox.Items = {'f(x)=exp(-x)', 'f(x)=sin(x)', 'f(x)=exp(-x^2)', 'f(x)=exp(-4x-x^3)'}; % Set list box items for integrals
                app.Table.ColumnName = {'Left Rectangles', 'Right Rectangles', 'Central Rectangles', 'Trapezoidal', 'Simpsons'};
                app.XYAxes.Visible = "off";
            elseif strcmp(problemType, 'Differential')
                app.y0EditField.Visible = 'on'; % Show y0 start value field
                app.ProblemsListBox.Items = {'y''=-xy', 'y''=-2xy', 'y''=-x(y^2)', 'y''=-2x(y^2)'}; % Set list box items for differentials
                app.Table.ColumnName = {'X', 'Y'};
                app.XYAxes.Visible = "on";
            end
        
            % Update dropdown menu based on problem type
            if strcmp(problemType, 'Integral')
                % Show only methods for solving integrals in dropdown
                integralsMethods = {'Left Rectangle', 'Right Rectangle', 'Central Rectangle', 'Trapeziodal', 'Simpsons'};
                app.MethodsDropDown.Items = integralsMethods;
            elseif strcmp(problemType, 'Differential')
                % Show only methods for solving differentials in dropdown
                differentialsMethods = {'Euler', 'Runge-Kutta 2nd Order', 'Runge-Kutta 3rd Order', 'Runge-Kutta 4th Order'};
                app.MethodsDropDown.Items = differentialsMethods;
            end
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            % Get the selected problem from the ProblemsListBox
            selected_problem = app.ProblemsListBox.Value;
            
            % Determine the type of problem (integral or differential)
            problem_type = app.ProblemTypeButtonGroup.SelectedObject.Text;
            
            % Prompt the user to select the method
            selected_method = app.MethodsDropDown.Value;
            
            % Parse the selected problem and extract parameters
            if strcmp(problem_type, 'Integral')
                f = {
                        @(x) exp(-x),               % f(x) = exp(-x)
                        @(x) sin(x),                % f(x) = sin(x)
                        @(x) exp(-x^2),             % f(x) = exp(-x^2)
                        @(x) exp(-4*x - x^3)        % f(x) = exp(-4x - x^3)
                    };
                
                % Initialize the result string
                result_string = '';
               
                % Initialize the result matrix for the integral values of the first method
                integral_values = zeros(1, 5); % 5 methods
        
                % Select the first integral function
                integral_function = f{1};
        
                % Iterate over each method
                methods = {'Left Rectangle', 'Right Rectangle', 'Central Rectangle', 'Trapezoidal', 'Simpsons'};
                for j = 1:numel(methods)
                    method = methods{j};
                    switch method
                        case 'Left Rectangle'
                            % Solve integral problem using left rectangles method
                            I = left_rectangles_integration(integral_function, app.UpperboundEditField.Value, app.LowerboundEditField.Value, app.StepEditField.Value);
                        case 'Right Rectangle'
                            % Solve integral problem using right rectangles method
                            I = right_rectangles_integration(integral_function, app.UpperboundEditField.Value, app.LowerboundEditField.Value, app.StepEditField.Value);
                        case 'Central Rectangle'
                            % Solve integral problem using central rectangles method
                            I = central_rectangles_integration(integral_function, app.UpperboundEditField.Value, app.LowerboundEditField.Value, app.StepEditField.Value);
                        case 'Trapezoidal'
                            % Solve integral problem using trapezoidal method
                            I = trapezoidal_integration(integral_function, app.UpperboundEditField.Value, app.LowerboundEditField.Value, app.StepEditField.Value);
                        case 'Simpsons'
                            % Solve integral problem using simpsons method
                            I = simpsons_integration(integral_function, app.UpperboundEditField.Value, app.LowerboundEditField.Value, app.StepEditField.Value);
                    end
                    % Store the integral value in the result matrix
                    integral_values(j) = I;
                end
        
                % Update the IntegralTable with the integral values
                app.Table.Data = integral_values;

                % Iterate over each integral function
                for i = 1:numel(f)
                    integral_function = f{i};

                    I = left_rectangles_integration(integral_function, app.UpperboundEditField.Value, app.LowerboundEditField.Value, app.StepEditField.Value);
                    
                    % Perform integration using the selected method
                    switch selected_method
                        case 'Left Rectangle'
                            % Solve integral problem using left rectangles method
                            I = left_rectangles_integration(integral_function, app.UpperboundEditField.Value, app.LowerboundEditField.Value, app.StepEditField.Value);
                        case 'Right Rectangle'
                            % Solve integral problem using right rectangles method
                            I = right_rectangles_integration(integral_function, app.UpperboundEditField.Value, app.LowerboundEditField.Value, app.StepEditField.Value);
                        case 'Central Rectangle'
                            % Solve integral problem using central rectangles method
                            I = central_rectangles_integration(integral_function, app.UpperboundEditField.Value, app.LowerboundEditField.Value, app.StepEditField.Value);
                        case 'Trapezoidal'
                            % Solve integral problem using trapezoidal method
                            I = trapezoidal_integration(integral_function, app.UpperboundEditField.Value, app.LowerboundEditField.Value, app.StepEditField.Value);
                        case 'Simpsons'
                            % Solve integral problem using simpsons method
                            I = simpsons_integration(integral_function, app.UpperboundEditField.Value, app.LowerboundEditField.Value, app.StepEditField.Value);
                    end
                    
                    % Append the result for the current integral and method to the result string
                    result_string = [result_string sprintf('Integral value for function %d using method %s: %f\n', i, selected_method, I)];
                end

                % Update the ResultTextArea with the result string
                app.ResultTextArea.Value = result_string;
            else
                f = {
                    @(x, y) -x*y,            % y' = -xy
                    @(x, y) -2*x*y,          % y' = -2xy
                    @(x, y) -x*(y^2),        % y' = -x(y^2)
                    @(x, y) -2*x*(y^2)       % y' = -2x(y^2)
                };
        
                % Initialize variables for storing results
                result_string = '';
                
                % Initialize variables for storing X and Y values
                all_x_values = {};
                all_y_values = {};
        
                % Iterate over each differential function
                for i = 1:numel(f)
                    differential_function = f{i};
                    % Initialize variables for storing results for the current differential equation
                    x_values = [];
                    y_values = [];
        
                    % Solve differential problem using the selected method
                    switch selected_method
                        case 'Euler'
                            % Solve differential problem using Euler's method
                            [x_values, y_values] = euler_method(differential_function, app.UpperboundEditField.Value, app.LowerboundEditField.Value, app.y0EditField.Value, app.StepEditField.Value);
                        case 'Runge-Kutta 2nd Order'
                            % Solve differential problem using Runge-Kutta of the second order method
                            [x_values, y_values] = runge_kutta_second_order(differential_function, app.UpperboundEditField.Value, app.LowerboundEditField.Value, app.y0EditField.Value, app.StepEditField.Value);
                        case 'Runge-Kutta 3rd Order'
                            % Solve differential problem using Runge-Kutta of the third order method
                            [x_values, y_values] = runge_kutta_third_order(differential_function, app.UpperboundEditField.Value, app.LowerboundEditField.Value, app.y0EditField.Value, app.StepEditField.Value);
                        case 'Runge-Kutta 4th Order'
                            % Solve differential problem using Runge-Kutta of the forth order method
                            [x_values, y_values] = runge_kutta_fourth_order(differential_function, app.UpperboundEditField.Value, app.LowerboundEditField.Value, app.y0EditField.Value, app.StepEditField.Value);
                    end
        
                    % Append the results for the current differential and method to the result string
                    result_string = [result_string sprintf('x values for differential %d using method %s: %s\n', i, selected_method, num2str(x_values))];
                    result_string = [result_string sprintf('y values for differential %d using method %s: %s\n\n', i, selected_method, num2str(y_values))];
        
                    % Store X and Y values for plotting
                    all_x_values{i} = x_values;
                    all_y_values{i} = y_values;
                end
        
                % Display the result for all differentials and methods
                app.ResultTextArea.Value = result_string;
        
                % Update the table with X and Y values
                app.Table.Data = [all_x_values{1}', all_y_values{1}'];
        
                % Plot X and Y values on the XYAxes
                cla(app.XYAxes); % Clear previous plot
                hold(app.XYAxes, 'on');
                for i = 1:numel(all_x_values)
                    plot(app.XYAxes, all_x_values{i}, all_y_values{i});
                end
                hold(app.XYAxes, 'off');
                xlabel(app.XYAxes, 'X');
                ylabel(app.XYAxes, 'Y');
                title(app.XYAxes, 'Graph of Differential Equations');
                legend(app.XYAxes, 'Differential 1', 'Differential 2', 'Differential 3', 'Differential 4');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XYAxes = uiaxes(app.UIFigure);
            title(app.XYAxes, 'Table of first differential')
            xlabel(app.XYAxes, 'X')
            ylabel(app.XYAxes, 'Y')
            zlabel(app.XYAxes, 'Z')
            app.TakeaMethodDropDownLabel = uilabel(app.UIFigure);
            app.TakeaMethodDropDownLabel.Text = 'Take a Method';
            app.MethodsDropDown = uidropdown(app.UIFigure);
            app.MethodsDropDown.Items = {'Left Rectangle', 'Right Rectangle', 'Central Rectangle', 'Trapezoidal', 'Simpsons', 'Euler', 'Runge Kutta (2nd order)', 'Runge Kutta (3rd order)', 'Runge Kutta (4th order)'};
            app.MethodsDropDown.Value = 'Left Rectangle';
            app.ProblemTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ProblemTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProblemTypeButtonGroupValueChanged, true);
            app.ProblemTypeButtonGroup.Title = 'Choose a problem to solve';
            app.IntegralButton = uiradiobutton(app.ProblemTypeButtonGroup);
            app.IntegralButton.Text = 'Integral';
            app.IntegralButton.Value = true;
            app.DifferentialButton = uiradiobutton(app.ProblemTypeButtonGroup);
            app.DifferentialButton.Text = 'Differential';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve all';
            app.StepnumberEditFieldLabel = uilabel(app.UIFigure);
            app.StepnumberEditFieldLabel.Text = 'Step number';
            app.StepEditField = uieditfield(app.UIFigure, 'numeric');
            app.y0startvalueEditFieldLabel = uilabel(app.UIFigure);
            app.y0startvalueEditFieldLabel.Text = 'y0 start value';
            app.y0EditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.ListofproblemsListBoxLabel = uilabel(app.UIFigure);
            app.ListofproblemsListBoxLabel.Text = 'List of problems';
            app.ProblemsListBox = uilistbox(app.UIFigure);
            app.ProblemsListBox.Items = {'f(x)=exp(−x)', 'f(x)=sin(x)', 'f(x)=exp(−x^2)', 'f(x)=exp(−4x−x^3)'};
            app.ProblemsListBox.Value = 'f(x)=exp(−x)';
            app.UpperboundEditFieldLabel = uilabel(app.UIFigure);
            app.UpperboundEditFieldLabel.Text = 'Upper bound';
            app.UpperboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerboundEditFieldLabel = uilabel(app.UIFigure);
            app.LowerboundEditFieldLabel.Text = 'Lower bound';
            app.LowerboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.Table = uitable(app.UIFigure);
            app.Table.ColumnName = {'Left Rectangles'; 'Right Rectangles'; 'Central Rectangles'; 'Trapezoidal'; 'Simpsons'};
            app.Table.RowName = {};
            app.FirstproblemtableLabel = uilabel(app.UIFigure);
            app.FirstproblemtableLabel.Text = 'First problem table';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IntegralsAndDifferentialsCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end