classdef CalculatorOfInterpolations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        XEditField             matlab.ui.control.EditField
        XEditFieldLabel        matlab.ui.control.Label
        fEditField             matlab.ui.control.EditField
        fEditFieldLabel        matlab.ui.control.Label
        xEditField             matlab.ui.control.EditField
        xEditField_2Label      matlab.ui.control.Label
        UITable_2              matlab.ui.control.Table
        UITable                matlab.ui.control.Table
        SolveButton            matlab.ui.control.Button
        InputFileButton        matlab.ui.control.Button
        MethodDropDown         matlab.ui.control.DropDown
        MethodDropDownLabel    matlab.ui.control.Label
        OperationsButtonGroup  matlab.ui.container.ButtonGroup
        DifferentiationButton  matlab.ui.control.RadioButton
        InterpolationButton    matlab.ui.control.RadioButton
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xData                  double = []
        fData                  double = []
        XData                  double = NaN
    end
    
    methods (Access = private)
        
        % Lagrange interpolation function
        function F = lagrange_interpolation(~, x, f, X)
            n = length(x);
            F = zeros(size(X));
            for k = 1:length(X)
                L = ones(n, 1);
                for i = 1:n
                    for j = 1:n
                        if i ~= j
                            L(i) = L(i) * (X(k) - x(j)) / (x(i) - x(j));
                        end
                    end
                end
                F(k) = sum(f .* L');
            end
        end
        
        % Newton interpolation function
        function F = newton_interpolation(~, x, f, X)
            n = length(x);
            b = zeros(n, n);
            b(:,1) = f(:);
            for j = 2:n
                for i = j:n
                    b(i,j) = (b(i,j-1) - b(i-1,j-1)) / (x(i) - x(i-j+1));
                end
            end
            F = zeros(size(X));
            for k = 1:length(X)
                F(k) = b(n,n);
                for j = n-1:-1:1
                    F(k) = F(k) * (X(k) - x(j)) + b(j,j);
                end
            end
        end
        
        % Differentiation using interpolation function for five nodes
        function F = differentiate_interpolation(app, x, f, X)
            n = length(x);
            m = length(X);
            F = zeros(1, m);
            for k = 1:m
                idx = app.find_nearest_index(x, X(k));
                nodes = max(1, idx - 2):min(n, idx + 2);
                coefficients = app.lagrange_coefficients(x(nodes), f(nodes));
                F(k) = app.differentiate_polynomial(x(nodes), coefficients, X(k));
            end
        end
        
        function idx = find_nearest_index(~, x, val)
            [~, idx] = min(abs(x - val));
        end

        function coefficients = lagrange_coefficients(~, x, f)
            n = length(x);
            coefficients = zeros(1, n);
            for i = 1:n
                numerator = 1;
                denominator = 1;
                for j = 1:n
                    if j ~= i
                        numerator = numerator * (x(i) - x(j));
                        denominator = denominator * (x(i) - x(j));
                    end
                end
                coefficients(i) = f(i) / numerator;
            end
        end

        function F = differentiate_polynomial(~, x, coefficients, X)
            n = length(x);
            F = 0;
            for i = 1:n
                prod = 1;
                for j = 1:n
                    if j ~= i
                        prod = prod * (X - x(j));
                    end
                end
                F = F + coefficients(i) * prod;
            end
        end
        
        % Approximate differentiation function
        function F = approximate_differentiation(~, x, f, X)
            n = length(x);
            m = length(X);
            F = zeros(1, m);
            
            % Check if x is uniformly spaced
            if any(diff(x) ~= x(2) - x(1))
                error('x must be uniformly spaced');
            end
        
            h = x(2) - x(1); % Step size, assuming uniform spacing
            
            for k = 1:m
                % Find the index of the closest node to X(k)
                [~, idx] = min(abs(x - X(k)));
                
                % Adjust index to ensure it's not too close to the edges
                if idx < 3
                    idx = 3;
                elseif idx > n - 2
                    idx = n - 2;
                end
                
                % Select five nodes around the closest index
                nodes = idx-2:idx+2;
                xx = x(nodes);
                ff = f(nodes);
                
                % Fit a polynomial of degree 4 to the selected nodes
                poly = polyfit(xx, ff, 4);
                
                % Differentiate the polynomial
                dpoly = polyder(poly);
                
                % Evaluate the derivative at X(k)
                F(k) = polyval(dpoly, X(k));
            end
        end
        
        % Function to read data from a .mat file
        function loadDataFromFile(app)
            [file, path] = uigetfile('*.mat');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                fullPath = fullfile(path, file);
                data = load(fullPath);
                if isfield(data, 'x') && isfield(data, 'f')
                    app.xData = data.x;
                    app.fData = data.f;
                    app.UITable.Data = [data.x(:), data.f(:)];
                    if isfield(data, 'X')
                        app.XData = data.X;
                    else
                        app.XData = NaN;
                    end
                else
                    disp('Invalid file format. The file should contain variables x and f, and optionally X.');
                end
            end
        end

        % Function to update the plot
        function updatePlot(app, x, f, X, F)
            % Clear existing plot
            cla(app.UIAxes);
            
            % Plot original data points
            plot(app.UIAxes, x, f, 'bo', 'DisplayName', 'Original Data');
            hold(app.UIAxes, 'on');
            
            % Plot interpolation or differentiation result
            plot(app.UIAxes, X, F, 'r-', 'DisplayName', 'Computed Result');
            
            % Add legend
            legend(app.UIAxes, 'show');
            
            % Release the hold
            hold(app.UIAxes, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: OperationsButtonGroup
        function OperationsButtonGroupSelectionChanged(app, event)
           % Show or hide the method selection based on the selected operation
            if app.InterpolationButton.Value
                app.MethodDropDownLabel.Visible = 'on';
                app.MethodDropDown.Visible = 'on';
                % Adjust method options for interpolation
                app.MethodDropDown.Items = {'Lagrange''s', 'Newton''s'};
            elseif app.DifferentiationButton.Value
                app.MethodDropDownLabel.Visible = 'on';
                app.MethodDropDown.Visible = 'on';
                % Adjust method options for differentiation
                app.MethodDropDown.Items = {'Differentiate', 'Approximate'};
            else
                app.MethodDropDownLabel.Visible = 'off';
                app.MethodDropDown.Visible = 'off';
            end
            
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
           if isempty(app.xData) || isempty(app.fData)
                disp('Data is empty or not in the expected format.');
                return;
            end
            
            x = app.xData;
            f = app.fData;
            X = app.XData;
            if isnan(X)
                X = str2double(app.XEditField.Value);
                if isnan(X)
                    disp('Invalid value for X');
                    return;
                end
            end
            
            if app.InterpolationButton.Value
                method = app.MethodDropDown.Value;
                if strcmp(method, 'Lagrange''s')
                    F = app.lagrange_interpolation(x, f, X);
                elseif strcmp(method, 'Newton''s')
                    F = app.newton_interpolation(x, f, X);
                end
            elseif app.DifferentiationButton.Value
                method = app.MethodDropDown.Value;
                if strcmp(method, 'Differentiate')
                    F = app.differentiate_interpolation(x, f, X);
                elseif strcmp(method, 'Approximate')
                    F = app.approximate_differentiation(x, f, X);
                end
            end
            
            app.UITable_2.Data = [X(:), F(:)];

            % Update the plot with the original and computed data
            app.updatePlot(x, f, X, F);
        end

        % Button pushed function: InputFileButton
        function InputFileButtonPushed(app, event)
            app.loadDataFromFile();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Results')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OperationsButtonGroup = uibuttongroup(app.UIFigure);
            app.OperationsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OperationsButtonGroupSelectionChanged, true);
            app.OperationsButtonGroup.Title = 'Operations';
            app.InterpolationButton = uiradiobutton(app.OperationsButtonGroup);
            app.InterpolationButton.Text = 'Interpolation';
            app.InterpolationButton.Value = true;
            app.DifferentiationButton = uiradiobutton(app.OperationsButtonGroup);
            app.DifferentiationButton.Text = 'Differentiation';
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Visible = 'off';
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Lagrange''s', 'Newton''s', 'Differentiate', 'Approximate'};
            app.MethodDropDown.Visible = 'off';
            app.MethodDropDown.Value = 'Lagrange''s';
            app.InputFileButton = uibutton(app.UIFigure, 'push');
            app.InputFileButton.ButtonPushedFcn = createCallbackFcn(app, @InputFileButtonPushed, true);
            app.InputFileButton.Text = 'Input File';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'x'; 'f'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'X'; 'F'};
            app.UITable_2.RowName = {};
            app.xEditField_2Label = uilabel(app.UIFigure);
            app.xEditField_2Label.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.fEditFieldLabel = uilabel(app.UIFigure);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.UIFigure, 'text');
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalculatorOfInterpolations

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end