classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        XEditField                 matlab.ui.control.EditField
        XEditFieldLabel            matlab.ui.control.Label
        FValuesEditFieldLabel      matlab.ui.control.EditField
        fLabel                     matlab.ui.control.Label
        XValuesEditFieldLabel      matlab.ui.control.EditField
        xLabel                     matlab.ui.control.Label
        OutputresultLabel          matlab.ui.control.Label
        InputvaluesLabel           matlab.ui.control.Label
        OutputTable                matlab.ui.control.Table
        InputTable                 matlab.ui.control.Table
        ChoosefiletoreadButton     matlab.ui.control.Button
        SolveButton                matlab.ui.control.Button
        ChoosemethodDropDown       matlab.ui.control.DropDown
        ChoosemethodDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        x % Input X values
        f % Input function values
        X % Interpolation/Differentiation points
    end
    
    methods (Access = private)
        
        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file, path] = uigetfile('*.mat');
            if isequal(file, 0)
                return;
            end
            data = load(fullfile(path, file));
            app.x = data.x;
            app.f = data.f;
            app.X = data.X;
            app.InputTable.Data = table(app.x', app.f', 'VariableNames', {'x', 'f'});
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            task = app.TaskSelectionDropDown.Value;
            method = app.MethodSelectionDropDown.Value;

            switch task
                case 'Interpolation'
                    switch method
                        case 'Lagrange'
                            F = lagrange_interpolation(app.x, app.f, app.X);
                        case 'Newton'
                            F = newton_interpolation(app.x, app.f, app.X);
                    end
                case 'Differentiation'
                    switch method
                        case 'Interpolation Method'
                            F = differentiate_interpolation(app.x, app.f, app.X);
                        case 'Approximation Method'
                            F = approximate_differentiation(app.x, app.f, app.X);
                    end
            end
            app.ResultTable.Data = table(app.X', F', 'VariableNames', {'X', 'F'});
        end
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChoosemethodDropDownLabel = uilabel(app.UIFigure);
            app.ChoosemethodDropDownLabel.Text = 'Choose method';
            app.ChoosemethodDropDown = uidropdown(app.UIFigure);
            app.ChoosemethodDropDown.Items = {'Lagrange''s Interpolation', 'Newton''s Interpolation', 'Differentiate Interpolation', 'Approximate Interpolation'};
            app.ChoosemethodDropDown.Value = 'Lagrange''s Interpolation';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.Text = 'Solve';
            app.ChoosefiletoreadButton = uibutton(app.UIFigure, 'push');
            app.ChoosefiletoreadButton.Text = 'Choose file to read';
            app.InputTable = uitable(app.UIFigure);
            app.InputTable.ColumnName = {'x'; 'f'; 'X'; ''};
            app.InputTable.RowName = {};
            app.OutputTable = uitable(app.UIFigure);
            app.OutputTable.ColumnName = {'Choosen method result'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.OutputTable.RowName = {};
            app.InputvaluesLabel = uilabel(app.UIFigure);
            app.InputvaluesLabel.Text = 'Input values';
            app.OutputresultLabel = uilabel(app.UIFigure);
            app.OutputresultLabel.Text = 'Output result';
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x';
            app.XValuesEditFieldLabel = uieditfield(app.UIFigure, 'text');
            app.fLabel = uilabel(app.UIFigure);
            app.fLabel.Text = 'f';
            app.FValuesEditFieldLabel = uieditfield(app.UIFigure, 'text');
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end